classdef hw2_21011017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FourierHesaplaButton     matlab.ui.control.Button
        b3EditField              matlab.ui.control.NumericEditField
        b3EditFieldLabel         matlab.ui.control.Label
        a3EditField              matlab.ui.control.NumericEditField
        a3EditFieldLabel         matlab.ui.control.Label
        b2EditField              matlab.ui.control.NumericEditField
        b2EditFieldLabel         matlab.ui.control.Label
        a2EditField              matlab.ui.control.NumericEditField
        a2EditFieldLabel         matlab.ui.control.Label
        b1EditField              matlab.ui.control.NumericEditField
        b1EditFieldLabel         matlab.ui.control.Label
        a1EditField              matlab.ui.control.NumericEditField
        a1EditFieldLabel         matlab.ui.control.Label
        a0EditField              matlab.ui.control.NumericEditField
        a0EditFieldLabel         matlab.ui.control.Label
        PeriyotTEditField        matlab.ui.control.NumericEditField
        PeriyotTEditFieldLabel   matlab.ui.control.Label
        GrafikCizButton          matlab.ui.control.Button
        FazEditField_3           matlab.ui.control.NumericEditField
        FazEditField_3Label      matlab.ui.control.Label
        FrekansEditField_3       matlab.ui.control.NumericEditField
        FrekansEditField_3Label  matlab.ui.control.Label
        GenlikEditField_3        matlab.ui.control.NumericEditField
        GenlikEditField_3Label   matlab.ui.control.Label
        FazEditField_2           matlab.ui.control.NumericEditField
        FazEditField_2Label      matlab.ui.control.Label
        FrekansEditField_2       matlab.ui.control.NumericEditField
        FrekansEditField_2Label  matlab.ui.control.Label
        GenlikEditField_2        matlab.ui.control.NumericEditField
        GenlikEditField_2Label   matlab.ui.control.Label
        FazEditField             matlab.ui.control.NumericEditField
        FazEditFieldLabel        matlab.ui.control.Label
        FrekansEditField         matlab.ui.control.NumericEditField
        FrekansEditFieldLabel    matlab.ui.control.Label
        GenlikEditField          matlab.ui.control.NumericEditField
        GenlikEditFieldLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrafikCizButton
        function GrafikCizButtonPushed(app, event)
            A1=app.GenlikEditField.Value;
            f1=app.FrekansEditField.Value;
            Q1=app.FazEditField.Value;
            
            A2=app.GenlikEditField_2.Value;
            f2=app.FrekansEditField_2.Value;
            Q2=app.FazEditField_2.Value;

            A3=app.GenlikEditField_3.Value;
            f3=app.FrekansEditField_3.Value;
            Q3=app.FazEditField_3.Value;
            
            F=gcd(f1,gcd(f2,f3));
            T=1/F;
            % Define the time vector
            t = linspace(0, 3*T, 1000);

            y1 = A1 * cos(2 * pi * f1 * t + Q1*pi);
            y2 = A2 * cos(2 * pi * f2 * t + Q2*pi);
            y3 = A3 * cos(2 * pi * f3 * t + Q3*pi);
            % Calculate the sum of the signals
            y_sum = y1 + y2 + y3;

            % Plot the signals on the UIAxes
            plot(app.UIAxes, t, y1, 'r', 'DisplayName', '1.Sinyal');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, y2, 'g', 'DisplayName', '2.Sinyal');
            plot(app.UIAxes, t, y3, 'b', 'DisplayName', '3.Sinyal');
            plot(app.UIAxes, t, y_sum, 'k', 'LineWidth', 1.5, 'DisplayName', 'Toplam Sinyali');
            hold(app.UIAxes, 'off');
            
            % Add legend and labels
            legend(app.UIAxes);
            title(app.UIAxes, 'Sinyaller ve Toplamı');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
        end

        % Button pushed function: FourierHesaplaButton
        function FourierHesaplaButtonPushed(app, event)
            a0 = app.a0EditField.Value;
            a1 = app.a1EditField.Value;
            b1 = app.b1EditField.Value;
            a2 = app.a2EditField.Value;
            b2 = app.b2EditField.Value;
            a3 = app.a3EditField.Value;
            b3 = app.b3EditField.Value;
            T = app.PeriyotTEditField.Value;
            w0=2*pi/T;
            
            % Time vector
            t = linspace(0, 3*T, 1000);
            
            % Fourier series components
            y0 = a0 * ones(size(t));
            y1 = a1 * cos(w0 * t) + b1 * sin(w0 * t);
            y2 = a2 * cos(2 * w0 * t) + b2 * sin(2 * w0 * t);
            y3 = a3 * cos(3 * w0 * t) + b3 * sin(3 * w0 * t);
            
            % Synthesis signal
            y_sum = y0 + y1 + y2 + y3;
            plot(app.UIAxes, t, y_sum, 'k', 'LineWidth', 1.5, 'DisplayName', 'Toplam Sinyali');
            hold(app.UIAxes, 'off');
            
            % Add legend and labels
            legend(app.UIAxes);
            title(app.UIAxes, 'Sinyaller ve Toplamı');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sinyaller ve Toplamı')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenlikEditFieldLabel = uilabel(app.UIFigure);
            app.GenlikEditFieldLabel.Text = '1.Genlik';
            app.GenlikEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrekansEditFieldLabel = uilabel(app.UIFigure);
            app.FrekansEditFieldLabel.Text = '1.Frekans';
            app.FrekansEditField = uieditfield(app.UIFigure, 'numeric');
            app.FazEditFieldLabel = uilabel(app.UIFigure);
            app.FazEditFieldLabel.Text = '1.Faz';
            app.FazEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenlikEditField_2Label = uilabel(app.UIFigure);
            app.GenlikEditField_2Label.Text = '2.Genlik';
            app.GenlikEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FrekansEditField_2Label = uilabel(app.UIFigure);
            app.FrekansEditField_2Label.Text = '2.Frekans';
            app.FrekansEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FazEditField_2Label = uilabel(app.UIFigure);
            app.FazEditField_2Label.Text = '2.Faz';
            app.FazEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GenlikEditField_3Label = uilabel(app.UIFigure);
            app.GenlikEditField_3Label.Text = '3.Genlik';
            app.GenlikEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FrekansEditField_3Label = uilabel(app.UIFigure);
            app.FrekansEditField_3Label.Text = '3.Frekans';
            app.FrekansEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FazEditField_3Label = uilabel(app.UIFigure);
            app.FazEditField_3Label.Text = '3.Faz';
            app.FazEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GrafikCizButton = uibutton(app.UIFigure, 'push');
            app.GrafikCizButton.ButtonPushedFcn = createCallbackFcn(app, @GrafikCizButtonPushed, true);
            app.GrafikCizButton.Text = 'Grafik Ciz';
            app.PeriyotTEditFieldLabel = uilabel(app.UIFigure);
            app.PeriyotTEditFieldLabel.Text = 'Periyot(T)';
            app.PeriyotTEditField = uieditfield(app.UIFigure, 'numeric');
            app.a0EditFieldLabel = uilabel(app.UIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.UIFigure, 'numeric');
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'numeric');
            app.b1EditFieldLabel = uilabel(app.UIFigure);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.UIFigure, 'numeric');
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'numeric');
            app.b2EditFieldLabel = uilabel(app.UIFigure);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.UIFigure, 'numeric');
            app.a3EditFieldLabel = uilabel(app.UIFigure);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.UIFigure, 'numeric');
            app.b3EditFieldLabel = uilabel(app.UIFigure);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.UIFigure, 'numeric');
            app.FourierHesaplaButton = uibutton(app.UIFigure, 'push');
            app.FourierHesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @FourierHesaplaButtonPushed, true);
            app.FourierHesaplaButton.Text = 'Fourier Hesapla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw2_21011017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end